classdef desenho < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DesenhoUIFigure    matlab.ui.Figure
        EscolherRede       matlab.ui.control.DropDown
        EscolherRedeLabel  matlab.ui.control.Label
        ResetButton        matlab.ui.control.Button
        ResultadoPanel     matlab.ui.container.Panel
        Resultado          matlab.ui.control.Label
        ClassificarButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        grayImage = ones(224,224);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            list = dir('*.mat'); % Vamos buscar todas a redes do Ficheiro atual
            app.EscolherRede.Items = {list.name};
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            hFH = drawfreehand(app.UIAxes); % Cria um objeto "freehand" na área 'app.UIAxes'

            % Obtem as coordenadas xy onde o User desenhou
            xy = hFH.Position;
            
            % Remover o objeto hFH pois as coordenadas já foram guardadas em 'xy'
            delete(hFH);
            hold(app.UIAxes, 'on');
            xCoordinates = xy(:, 1); % Extrair as coordenadas x do desenho da matriz 'xy'
            yCoordinates = xy(:, 2); % Extrair as coordenadas y do desenho da matriz 'xy'

            line(app.UIAxes, xCoordinates, yCoordinates, 'Color', 'red', 'LineWidth', 5); % Apresenta o desenho em tempo real 

            promptMessage = sprintf('Deseja utilizar esta imagem?');
            titleBarCaption = 'Continuar';
            button = questdlg(promptMessage, titleBarCaption, 'OK', 'Não', 'OK');

            if strcmpi(button, 'Não')
                cla(app.UIAxes);
                hold(app.UIAxes, 'off');
                app.ClassificarButton.Enable = false;
                app.ClassificarButton.BackgroundColor = [1.00,0.45,0.45];
                return;
            end

            app.ClassificarButton.Enable = true;
            app.ClassificarButton.BackgroundColor = [0.24,0.82,0.19];

            app.grayImage = ones(size(app.grayImage));
        
            % Desenhar as linhas definidas pelas coordenadas 'xCoordinates' e 'yCoordinates' na imagem 'app.grayImage'
            for k = 1 : length(xCoordinates)-1
                x1 = round(xCoordinates(k));
                y1 = round(yCoordinates(k));
                x2 = round(xCoordinates(k+1));
                y2 = round(yCoordinates(k+1));
        
                % Desenhar linhas -> 'app.grayImage'
                x = linspace(x1, x2, max(abs(x2 - x1), abs(y2 - y1))+1);
                y = linspace(y1, y2, max(abs(x2 - x1), abs(y2 - y1))+1);
            
                for i = 1:length(x)
                    app.grayImage(round(y(i)), round(x(i))) = 0;
                end
            end

            % Aumentar a espessura dos contornos das linhas
            contornos = bwperim(app.grayImage);
            espessura = 4; % Espessura desejada dos contornos
            elementoEstruturante = strel('disk', espessura);
            contornosEspessos = imdilate(contornos, elementoEstruturante);
        
            % Desenhar os contornos espessos na imagem
            app.grayImage(contornosEspessos) = 0;
        
            app.grayImage = flipud(app.grayImage); % Inverter a imagem

            % Salvar a imagem como um arquivo PNG
            imwrite(app.grayImage,'D:/Faculdade/2º-3ºAno/2º Semestre/CR/Projeto/imagem.png');
            

        end

        % Button pushed function: ClassificarButton
        function ClassificarButtonPushed(app, event)
            persistent i;
            persistent firstNumb;
            persistent op;
            persistent secondNumb; 
            resultado='';
            if isempty(i)
                i=0;
            else
                i=i+1;
            end

            if(app.EscolherRede.Value ~= "")
               if(i==0)
                   [~,firstNumb]=identificarImagem(app.EscolherRede.Value,0);
                   app.Resultado.Text = sprintf("[%d] %d",i+1,firstNumb);  
               elseif(i==1) % Operador
                   [op,~]=identificarImagem(app.EscolherRede.Value,1);
                   app.Resultado.Text = sprintf("[%d] %d %s",i+1,firstNumb,op);  
               elseif(i==2)
                   [~,secondNumb]=identificarImagem(app.EscolherRede.Value,0);
                   if op == '+'
                       resultado=firstNumb+secondNumb;
                   elseif op == '-'
                       resultado=firstNumb-secondNumb;
                   elseif op == ':'
                       resultado=firstNumb:secondNumb;
                   elseif op == 'x'
                       resultado=firstNumb*secondNumb;
                   elseif op == '?'
                       resultado=firstNumb+secondNumb;
                   end
                   app.Resultado.Text = sprintf("[%d] %d %s %d = %d",i+1,firstNumb,op,secondNumb,resultado);
                   i=[];
                   firstNumb=[];
                   secondNumb=[];
                   op=[]; 
               end
               cla(app.UIAxes);
               app.ClassificarButton.Enable = false;
               app.ClassificarButton.BackgroundColor = [1.00,0.45,0.45];
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            app.Resultado.Text = "";
            app.ClassificarButton.Enable = false;
            app.ClassificarButton.BackgroundColor = [1.00,0.45,0.45];
        end

        % Value changed function: EscolherRede
        function EscolherRedeValueChanged(app, event)
            value = app.EscolherRede.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DesenhoUIFigure = uifigure('Visible', 'off');
            app.DesenhoUIFigure.Name = 'Desenho';
            app.UIAxes = uiaxes(app.DesenhoUIFigure);
            title(app.UIAxes, 'Clique e arraste com o botão esquerdo do rato para desenhar')
            app.UIAxes.XLim = [0 224];
            app.UIAxes.YLim = [0 224];
            app.UIAxes.ZLim = [0 224];
            app.UIAxes.XTick = 0.224;
            app.UIAxes.YTick = 0.224;
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ClassificarButton = uibutton(app.DesenhoUIFigure, 'push');
            app.ClassificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarButtonPushed, true);
            app.ClassificarButton.Enable = 'off';
            app.ClassificarButton.Text = 'Classificar';
            app.ResultadoPanel = uipanel(app.DesenhoUIFigure);
            app.ResultadoPanel.Title = 'Resultado';
            app.Resultado = uilabel(app.ResultadoPanel);
            app.Resultado.Text = '';
            app.ResetButton = uibutton(app.DesenhoUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EscolherRedeLabel = uilabel(app.DesenhoUIFigure);
            app.EscolherRedeLabel.Text = 'Escolher Rede';
            app.EscolherRede = uidropdown(app.DesenhoUIFigure);
            app.EscolherRede.Items = {''};
            app.EscolherRede.ValueChangedFcn = createCallbackFcn(app, @EscolherRedeValueChanged, true);
            app.EscolherRede.Value = '';
            % Show the figure after all components are created
            app.DesenhoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = desenho

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DesenhoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DesenhoUIFigure)
        end
    end
end